classdef flow_1d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        P_inkPaSlider         matlab.ui.control.Slider
        P_inkPaSliderLabel    matlab.ui.control.Label
        muPasSlider           matlab.ui.control.Slider
        muPasSliderLabel      matlab.ui.control.Label
        phiSlider             matlab.ui.control.Slider
        phiSliderLabel        matlab.ui.control.Label
        Km2Slider             matlab.ui.control.Slider
        Km2Label              matlab.ui.control.Label
        LmSlider              matlab.ui.control.Slider
        LmSliderLabel         matlab.ui.control.Label
        StartAnimationButton  matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartAnimationButton
        function StartAnimationButtonPushed(app, event)
            % Composite Properties
            L = app.LmSlider.Value;       % Length of the composite [m]
            K = app.Km2Slider.Value * 1e-12;     % Permeability of the composite [m^2] (e.g., for a fiberglass composite)
            phi = app.phiSlider.Value;     % Porosity of the composite [dimensionless]
            
            % Fluid Properties
            mu = app.muPasSlider.Value;      % Viscosity of the fluid [Pa*s] (e.g., for a resin)
            
            % Process Conditions
            Pin = app.P_inkPaSlider.Value * 1e3;     % Inlet pressure [Pa]
            Pout = 0;      % Outlet pressure [Pa]
            deltaP = Pin - Pout; % Pressure drop across the composite [Pa]
            
            % Simulation Parameters
            t_total = 1e6; % Total simulation time [s]
            n_steps = 5000; % Number of time steps
            dt = t_total / n_steps; % Time step size [s]
            
            % --- 3. Calculations based on Darcy's Law ---
            
            % Calculate Darcy Velocity (superficial velocity)
            % v = (K/mu) * (deltaP/L)
            % v_darcy = (K / mu) * (deltaP / L);
            const_term = 2*K*deltaP/(mu*phi);
            
            % Calculate Seepage Velocity (actual fluid front velocity)
            % This is the Darcy velocity divided by the porosity, as the fluid
            % can only flow through the porous channels.
            % v_seepage = v_darcy / phi;
            
            % fprintf('Darcy Velocity: %.4f mm/s\n', v_darcy * 1000);
            % fprintf('Seepage Velocity: %.4f mm/s\n', v_seepage * 1000);
            
            % --- 4. Initialize Variables for Animation ---
            time_vec = 0:dt:t_total; % Time vector for the simulation
            x_front = zeros(size(time_vec)); % Initialize the position of the flow front
            
            % --- 5. Setup the Animation Figure ---
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            box(app.UIAxes, 'on');
            title(app.UIAxes, 'Flow Front Simulation (t = 0.00 s)');
            xlabel(app.UIAxes, 'Position along composite (m)');
            ylabel(app.UIAxes, ' ');
            set(app.UIAxes, 'YTick', []); % Hide y-axis ticks for a cleaner look
            axis(app.UIAxes, [0 L -1 1]);
            
            % Draw the empty composite (as a gray rectangle)
            rectangle(app.UIAxes, 'Position', [0, -0.5, L, 1], 'FaceColor', [0.8 0.8 0.8], 'EdgeColor', 'k', 'LineWidth', 1.5);
            
            % Draw the initial filled region (blue rectangle)
            % It starts with zero width and will expand as the flow front moves.
            filled_region = rectangle(app.UIAxes, 'Position', [0, -0.5, 0, 1], 'FaceColor', [0.3 0.6 1], 'EdgeColor', 'none');
            
            % Draw the flow front (as a vertical red line)
            flow_front_line = line(app.UIAxes, [0 0], [-0.5 0.5], 'Color', 'r', 'LineWidth', 2);
            
            % --- 6. Run the Animation Loop ---
            for i = 2:length(time_vec)
                % Update flow front position using the seepage velocity
                % x_front(i) = x_front(i-1) + v_seepage * dt;
                x_front(i)=sqrt(const_term*time_vec(i));
            
                % % Ensure the flow front does not exceed the composite length
                % if x_front(i) > L
                %     x_front(i) = L;
                % end
            
                % Update the graphics objects
                % Update the width of the blue 'filled' rectangle
                set(filled_region, 'Position', [0, -0.5, x_front(i), 1]);
            
                % Update the position of the red flow front line
                set(flow_front_line, 'XData', [x_front(i) x_front(i)]);
            
                % Update the title with current time and fill percentage
                fill_percentage = (x_front(i) / app.LmSlider.Value) * 100;
                title(app.UIAxes, sprintf('Time: %.2f hrs | Fill: %.1f%%', time_vec(i)/3600, fill_percentage));
            
                % Pause to control animation speed
                pause(0.01);
            
                % Stop the animation if the composite is full
                if x_front(i) >= app.LmSlider.Value
                    title(app.UIAxes, sprintf('Fill Complete in %.2f hrs', time_vec(i)/3600));
                    break; % Exit the loop
                end
            end
            
            hold(app.UIAxes, 'off');
            disp('Animation complete.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartAnimationButton = uibutton(app.UIFigure, 'push');
            app.StartAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnimationButtonPushed, true);
            app.StartAnimationButton.Text = 'Start Animation';
            app.LmSliderLabel = uilabel(app.UIFigure);
            app.LmSliderLabel.Text = 'L (m)';
            app.LmSlider = uislider(app.UIFigure);
            app.LmSlider.Limits = [0 1];
            app.LmSlider.Value = 0.5;
            app.Km2Label = uilabel(app.UIFigure);
            app.Km2Label.Text = 'K (μm^2)';
            app.Km2Slider = uislider(app.UIFigure);
            app.Km2Slider.Limits = [0 10000];
            app.Km2Slider.Value = 5000;
            app.phiSliderLabel = uilabel(app.UIFigure);
            app.phiSliderLabel.Text = 'phi';
            app.phiSlider = uislider(app.UIFigure);
            app.phiSlider.Limits = [0 1];
            app.phiSlider.Value = 0.5;
            app.muPasSliderLabel = uilabel(app.UIFigure);
            app.muPasSliderLabel.Text = 'mu (Pa.s)';
            app.muPasSlider = uislider(app.UIFigure);
            app.muPasSlider.Limits = [0 1000];
            app.muPasSlider.Value = 500;
            app.P_inkPaSliderLabel = uilabel(app.UIFigure);
            app.P_inkPaSliderLabel.Text = 'P_in (kPa)';
            app.P_inkPaSlider = uislider(app.UIFigure);
            app.P_inkPaSlider.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flow_1d

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end